classdef LagrageApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        xEditField                   matlab.ui.control.NumericEditField
        xEditFieldLabel              matlab.ui.control.Label
        PuntoxLabel                  matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        PuntoXquedeseainterpolarEditField  matlab.ui.control.NumericEditField
        PuntoXquedeseainterpolarEditFieldLabel  matlab.ui.control.Label
        CalcularButton               matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        OKButton                     matlab.ui.control.Button
        messageError                 matlab.ui.control.Label
        PorfavoringreselospuntosenelformatoxyLabel  matlab.ui.control.Label
        CuntospuntosconocidosdeseaingresarEditField  matlab.ui.control.NumericEditField
        CuntospuntosconocidosdeseaingresarEditFieldLabel  matlab.ui.control.Label
        INTERPOLACINDELAGRANGELabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        contador = 1;
        total;
        x;
        y;
        puntoInterpolar;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % CuntospuntosconocidosdeseaingresarEditField
        function CuntospuntosconocidosdeseaingresarEditFieldValueChanged(app, event)
            value = app.CuntospuntosconocidosdeseaingresarEditField.Value;
            if isempty(value) || value <= 0 || mod(value,1) ~= 0
                app.messageError.Text ='Error: Debe ingresar un número entero positivo mayor a cero.';
                app.Panel.Visible = true;
            end
            app.x = zeros(1, value);
            app.y = zeros(1, value);
            app.total = value;
            app.PuntoxLabel.Text = sprintf('Punto %d:', app.contador);
            app.CuntospuntosconocidosdeseaingresarEditField.Enable = false;
            app.xEditField.focus;
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.Panel.Visible = false;
            app.CuntospuntosconocidosdeseaingresarEditField.Enable = true;
            app.CuntospuntosconocidosdeseaingresarEditField.focus;
        end

        % Callback function
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.PuntoxLabel.Text = sprintf('Punto %d:', app.contador);

            app.x(app.contador) = value;
            
            app.yEditField.focus;
           
        
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            resultado = 0;
        for i = 1:app.total
               % Polinomio base
            Li = 1;
            for j = 1:app.total
               if i ~= j
                Li = Li * ((app.puntoInterpolar - app.x(j)) / (app.x(i) - app.x(j)));
               end
            end
           resultado = resultado + (app.y(i) * Li);
        end
        yint = resultado;

        % --- PROCESO DE GRAFICACIÓN EN EL UIAXIS ---
            % Limpiar el eje antes de redibujar para evitar sobreposición
            cla(app.UIAxes);
            
            % 1. Puntos originales (Nodos conocidos)
          
            plot(app.UIAxes,app.x, app.y, 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b')
            hold(app.UIAxes, 'on')
            
            % 2. Punto interpolado (Asterisco rojo)
            fprintf('El valor interpolado es: %.4f\n', yint);
            scatter(app.UIAxes, app.puntoInterpolar, yint, 80, 'r', '*', 'LineWidth', 2)
            
            % 3. Generar polinomio continuo para la curva suave
            margen = (max(app.x) - min(app.x)) * 0.1;
            if margen == 0, margen = 1; end 
            
            x_curva = linspace(min(app.x) - margen, max(app.x) + margen, 200); 
            y_curva = zeros(size(x_curva));
            

            for k = 1:length(x_curva)
                xk = x_curva(k);
                rk = 0;
                for i = 1:app.total
                    Lik = 1;
                    for j = 1:app.total
                        if i ~= j
                            Lik = Lik * ((xk - app.x(j)) / (app.x(i) - app.x(j)));
                        end
                    end
                    rk = rk + (app.y(i) * Lik);
                end
                y_curva(k) = rk;
            end
            
            % 4. Dibujar la curva polinómica
            plot(app.UIAxes, x_curva, y_curva, 'b-', 'LineWidth', 1.5)
            
            % Ajustes de entorno del UIAxis
            xlim(app.UIAxes, [min(app.x)-margen, max(app.x)+margen]);
            grid(app.UIAxes, 'on')
            grid(app.UIAxes, 'minor')
            
            title(app.UIAxes, 'Interpolación Polinómica de Lagrange (Modelo Genérico)', 'FontSize', 13)
            xlabel(app.UIAxes, 'Eje X (Variable Independiente)')
            ylabel(app.UIAxes, 'Eje Y (Variable Dependiente)')
            
            legend(app.UIAxes, 'Puntos Conocidos proporcionados (Nodos)', ...
                   sprintf('Punto Interpolado (%.2f, %.2f)', app.puntoInterpolar, yint), ...
                   'Polinomio Interpolador de Lagrange', ...
                   'Location', 'best')
               
            hold(app.UIAxes, 'off')
           

        end

        % Value changed function: PuntoXquedeseainterpolarEditField
        function PuntoXquedeseainterpolarEditFieldValueChanged(app, event)
            value = app.PuntoXquedeseainterpolarEditField.Value;
            app.puntoInterpolar = value;
            app.PuntoXquedeseainterpolarEditField.Enable = false;
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            app.y(app.contador) = value;
            if app.contador <= app.total
                app.contador = app.contador + 1;
                app.yEditField.Value = 0;
                app.xEditField.Value = 0;
                app.PuntoxLabel.Text = sprintf('Punto %d:', app.contador);
                app.xEditField.focus;
            end
            if app.contador > app.total
                app.PuntoxLabel.Text = sprintf('Punto %d:', app.contador -1);
                app.xEditField.Enable = false;
                app.yEditField.Enable = false;
                app.PuntoXquedeseainterpolarEditField.focus;
            end
            
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged2(app, event)
            value = app.xEditField.Value;
            app.PuntoxLabel.Text = sprintf('Punto %d:', app.contador);
            app.x(app.contador) = value;
            app.yEditField.focus;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.INTERPOLACINDELAGRANGELabel = uilabel(app.UIFigure);
            app.INTERPOLACINDELAGRANGELabel.Text = 'INTERPOLACIÓN DE LAGRANGE';
            app.CuntospuntosconocidosdeseaingresarEditFieldLabel = uilabel(app.UIFigure);
            app.CuntospuntosconocidosdeseaingresarEditFieldLabel.Text = '¿Cuántos puntos conocidos desea ingresar?:';
            app.CuntospuntosconocidosdeseaingresarEditField = uieditfield(app.UIFigure, 'numeric');
            app.CuntospuntosconocidosdeseaingresarEditField.ValueChangedFcn = createCallbackFcn(app, @CuntospuntosconocidosdeseaingresarEditFieldValueChanged, true);
            app.PorfavoringreselospuntosenelformatoxyLabel = uilabel(app.UIFigure);
            app.PorfavoringreselospuntosenelformatoxyLabel.Text = 'Por favor, ingrese los puntos en el formato (x,y):';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.Visible = 'off';
            app.messageError = uilabel(app.Panel);
            app.messageError.Text = 'Error';
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.PuntoXquedeseainterpolarEditFieldLabel = uilabel(app.UIFigure);
            app.PuntoXquedeseainterpolarEditFieldLabel.Text = 'Punto X que desea interpolar:';
            app.PuntoXquedeseainterpolarEditField = uieditfield(app.UIFigure, 'numeric');
            app.PuntoXquedeseainterpolarEditField.ValueChangedFcn = createCallbackFcn(app, @PuntoXquedeseainterpolarEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.PuntoxLabel = uilabel(app.UIFigure);
            app.PuntoxLabel.Text = 'Punto x:';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged2, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LagrageApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end