classdef TaylorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraficacindeSeriedeTaylorUIFigure  matlab.ui.Figure
        GraficacindePolinomiosdeTaylorLabel  matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ErrorPorcentualLabel            matlab.ui.control.Label
        ErrorLabel                      matlab.ui.control.Label
        ValorAproximadoLabel            matlab.ui.control.Label
        ValorRealLabel                  matlab.ui.control.Label
        PolinomioLabel                  matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        ValordelincrementohEditField    matlab.ui.control.NumericEditField
        ValordelincrementohEditFieldLabel  matlab.ui.control.Label
        GradodelpolinomionEditField     matlab.ui.control.NumericEditField
        GradodelpolinomionLabel         matlab.ui.control.Label
        PuntodeexpansinaEditField       matlab.ui.control.NumericEditField
        PuntodeexpansinaEditFieldLabel  matlab.ui.control.Label
        FuncinacalcularfxEditField      matlab.ui.control.EditField
        FuncinacalcularfxEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            syms x
            f = app.FuncinacalcularfxEditField.Value;
            a = app.PuntodeexpansinaEditField.Value;
            n = app.GradodelpolinomionEditField.Value;
            h = app.ValordelincrementohEditField.Value;

            xi_1 = a + h;

            dominio = [a xi_1];
    
            T = taylor(str2sym(f), x, 'ExpansionPoint', a, 'Order', n+1);

            valorAproximado = subs(T, x, xi_1);
            valorReal = subs(str2sym(f), x, xi_1);


            polinomio = T;                        

            Et = valorReal - valorAproximado;
            Ep = abs(Et / valorReal) * 100;
            cla(app.UIAxes);
            fplot(app.UIAxes,f,dominio,'--r','LineWidth',3);
            hold(app.UIAxes,'on');
            fplot(app.UIAxes,T,dominio,'--g','LineWidth',2);
            legend(app.UIAxes,'Funcion real','Taylor');
            grid(app.UIAxes,"on");

            app.Label.Text = string(polinomio);
            app.Label_2.Text = string(valorReal);
            app.Label_3.Text = string(valorAproximado);
            app.Label_4.Text = string(double(Et));
            app.Label_5.Text = string(double(Ep));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraficacindeSeriedeTaylorUIFigure = uifigure('Visible', 'off');
            app.GraficacindeSeriedeTaylorUIFigure.Name = 'Graficación de Serie de Taylor';
            app.UIAxes = uiaxes(app.GraficacindeSeriedeTaylorUIFigure);
            title(app.UIAxes, 'Grafica de funcion y Polinomio de Taylor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FuncinacalcularfxEditFieldLabel = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.FuncinacalcularfxEditFieldLabel.Text = 'Función a calcular: f(x)';
            app.FuncinacalcularfxEditField = uieditfield(app.GraficacindeSeriedeTaylorUIFigure, 'text');
            app.PuntodeexpansinaEditFieldLabel = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.PuntodeexpansinaEditFieldLabel.Text = 'Punto de expansión - a:';
            app.PuntodeexpansinaEditField = uieditfield(app.GraficacindeSeriedeTaylorUIFigure, 'numeric');
            app.GradodelpolinomionLabel = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.GradodelpolinomionLabel.Text = 'Grado del polinomio - n:';
            app.GradodelpolinomionEditField = uieditfield(app.GraficacindeSeriedeTaylorUIFigure, 'numeric');
            app.ValordelincrementohEditFieldLabel = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.ValordelincrementohEditFieldLabel.Text = 'Valor del incremento - h:';
            app.ValordelincrementohEditField = uieditfield(app.GraficacindeSeriedeTaylorUIFigure, 'numeric');
            app.CalcularButton = uibutton(app.GraficacindeSeriedeTaylorUIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.PolinomioLabel = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.PolinomioLabel.Text = 'Polinomio:';
            app.ValorRealLabel = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.ValorRealLabel.Text = 'Valor Real:';
            app.ValorAproximadoLabel = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.ValorAproximadoLabel.Text = 'Valor Aproximado:';
            app.ErrorLabel = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.ErrorLabel.Text = 'Error:';
            app.ErrorPorcentualLabel = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.ErrorPorcentualLabel.Text = 'Error Porcentual:';
            app.Label = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.Label_5.Text = '';
            app.GraficacindePolinomiosdeTaylorLabel = uilabel(app.GraficacindeSeriedeTaylorUIFigure);
            app.GraficacindePolinomiosdeTaylorLabel.Text = 'Graficación de Polinomios de Taylor';
            % Show the figure after all components are created
            app.GraficacindeSeriedeTaylorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TaylorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraficacindeSeriedeTaylorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraficacindeSeriedeTaylorUIFigure)
        end
    end
end